classdef T1mapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappingUIFigure            matlab.ui.Figure
        DashboardPanel               matlab.ui.container.Panel
        LoadDataButton               matlab.ui.control.Button
        MRSolutionsCheckBox          matlab.ui.control.CheckBox
        BrukerCheckBox               matlab.ui.control.CheckBox
        ReconstructButton            matlab.ui.control.Button
        RegistrationButton           matlab.ui.control.Button
        SegmentButton                matlab.ui.control.Button
        RegistrationProgressGauge    matlab.ui.control.LinearGauge
        FitButton                    matlab.ui.control.Button
        ExportDicomButton            matlab.ui.control.Button
        ExportNIFTIButton            matlab.ui.control.Button
        ExportGIFButton              matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        RegistrationProgressLabel    matlab.ui.control.Label
        FitProgressGauge             matlab.ui.control.LinearGauge
        FitProgressLabel             matlab.ui.control.Label
        AbortButton                  matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Lamp5                        matlab.ui.control.Lamp
        Lamp6                        matlab.ui.control.Lamp
        Lamp7                        matlab.ui.control.Lamp
        Lamp8                        matlab.ui.control.Lamp
        TitleLabel                   matlab.ui.control.Label
        RawimagesPanel               matlab.ui.container.Panel
        RawImage                     matlab.ui.control.UIAxes
        ImageSelectorPanel           matlab.ui.container.Panel
        SliceEditFieldLabel          matlab.ui.control.Label
        SliceEditField               matlab.ui.control.NumericEditField
        SliceSlider                  matlab.ui.control.Slider
        ImageEditFieldLabel          matlab.ui.control.Label
        ImageEditField               matlab.ui.control.NumericEditField
        ImageSlider                  matlab.ui.control.Slider
        OrientationSpinnerLabel      matlab.ui.control.Label
        OrientationSpinner           matlab.ui.control.Spinner
        SegmentationPanel            matlab.ui.container.Panel
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdSlider              matlab.ui.control.Slider
        ErodeButton                  matlab.ui.control.Button
        DilateButton                 matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        IntensitySlider              matlab.ui.control.Slider
        IntensityLabel               matlab.ui.control.Label
        ImageLabel                   matlab.ui.control.Label
        CreditsLabel                 matlab.ui.control.Label
        TulpImage                    matlab.ui.control.Image
        MessagesPanel                matlab.ui.container.Panel
        MessagesBox                  matlab.ui.control.TextArea
        SequenceparametersPanel      matlab.ui.container.Panel
        TREditFieldLabel             matlab.ui.control.Label
        TRViewField                  matlab.ui.control.NumericEditField
        TEEditFieldLabel             matlab.ui.control.Label
        TEViewField                  matlab.ui.control.NumericEditField
        SequenceEditFieldLabel       matlab.ui.control.Label
        SequenceViewField            matlab.ui.control.EditField
        MatrixEditFieldLabel         matlab.ui.control.Label
        MatrixViewField1             matlab.ui.control.NumericEditField
        MatrixViewField2             matlab.ui.control.NumericEditField
        MatrixViewField3             matlab.ui.control.NumericEditField
        FOVViewField1                matlab.ui.control.NumericEditField
        FOVViewField2                matlab.ui.control.NumericEditField
        FOVmmLabel                   matlab.ui.control.Label
        FOVViewField3                matlab.ui.control.NumericEditField
        FlipanglesEditFieldLabel     matlab.ui.control.Label
        FlipanglesEditField          matlab.ui.control.EditField
        FitresultsPanel              matlab.ui.container.Panel
        T1Image                      matlab.ui.control.UIAxes
        M0Image                      matlab.ui.control.UIAxes
        T1Label                      matlab.ui.control.Label
        M0Label                      matlab.ui.control.Label
        FitImageSelectorPanel        matlab.ui.container.Panel
        SliceEditField_2Label_2      matlab.ui.control.Label
        FitSliceEditField            matlab.ui.control.NumericEditField
        FitSliceSlider               matlab.ui.control.Slider
        OrientationSpinner_2Label_2  matlab.ui.control.Label
        FitOrientationSpinner        matlab.ui.control.Spinner
        ColormapButtonGroup          matlab.ui.container.ButtonGroup
        grayButton                   matlab.ui.control.RadioButton
        autumnButton                 matlab.ui.control.RadioButton
        winterButton                 matlab.ui.control.RadioButton
        parulaButton                 matlab.ui.control.RadioButton
        hotButton                    matlab.ui.control.RadioButton
        EditField                    matlab.ui.control.NumericEditField
        MaxLabel                     matlab.ui.control.Label
        AutoButton                   matlab.ui.control.Button
        T1FitFig                     matlab.ui.control.UIAxes
        DespotT1FitFIg               matlab.ui.control.UIAxes
    end


    %% ----------------------------------------------------------------------------
    %
    %   3D T1 mapping 
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %
    %
    %   Version 1.0
    %
    %   - initial version
    %
    %
    %
    %% ----------------------------------------------------------------------------


    properties (Access = private)
        
        valid_datafile              % valid data file true or false
        valid_fit                   % valid fit data true or false
        data_import_path            % data import path
        
    end


    % ----------------------------------------------------------------------------


    methods (Access = private)
        
          function SetLights(app,code)
           
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            if ~strcmp(code{7},'Null') app.Lamp7.Color = code{7}; end
            if ~strcmp(code{8},'Null') app.Lamp8.Color = code{8}; end
            drawnow;
    
        end
        
        
        function result = GetLights(app)
           
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            result{5} = app.Lamp5.Color;
            result{6} = app.Lamp6.Color;
            result{7} = app.Lamp7.Color;
            result{8} = app.Lamp8.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadDataButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.ReconstructButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.RegistrationButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.SegmentationButton.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.FitButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportDicomButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.ExportNIFTIButton.Enable = code{7}; end
            if ~strcmp(code{8},'Null') app.ExportGIFButton.Enable = code{8}; end
            if ~strcmp(code{9},'Null') app.ExitButton.Enable = code{9}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadDataButton.Enable;
            result{2} = app.ReconstructButton.Enable;
            result{3} = app.RegistrationButton.Enable;
            result{4} = app.SegmentationButton.Enable;
            result{5} = app.FitButton.Enable;
            result{6} = app.ExportDicomButton.Enable;
            result{7} = app.ExportNIFTIButton.Enable;
            result{8} = app.ExportGIFButton.Enable;
            result{9} = app.ExitButton.Enable;
            
        end
        
    end


    % ----------------------------------------------------------------------------


    methods (Access = public)
        
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        function ShowRegistrationProgress(app, progress)
            
            % Update the progress bar
            app.RegistrationProgressGauge.Value = progress;
            
        end % ShowRegistrationProgress
        
        
        function ShowFitProgress(app, progress)
            
            % Update the progress bar
            app.FitProgressGauge.Value = progress;
            
        end % ShowFitProgress
        
        
    end


    % ----------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % empty file import and export paths
            app.data_import_path = '';
            app.dicom_export_path = '';
            app.nifti_export_path = '';
            app.gif_export_path = '';
            
            % add the bart path to the environment variable if available
            app.bart_detected = false;
            if isfile('bartpath.txt')
                app.bart_detected = true;
                setenv('TOOLBOX_PATH', fileread('bartpath.txt'));
            end
            
            % add subfolders to search path
            if (~isdeployed)
                addpath(genpath(pwd));
            end
            
            % suppress unneccesary warnings
            warning off;
                    
        end

        % Value changed function: MRSolutionsCheckBox
        function MRSolutionsCheckBoxValueChanged(app, event)
            
            % set data format to MR Solutions
            app.BrukerCheckBox.Value = 0;
            app.MRSolutionsCheckBox.Value = 1;
            
        end

        % Value changed function: BrukerCheckBox
        function BrukerCheckBoxValueChanged(app, event)
            
            % set data format to Bruker
            app.BrukerCheckBox.Value = 1;
            app.MRSolutionsCheckBox.Value = 0;
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
        
            % Indicate busy
            %SetLights(app,{'Yellow','Null','Null','Null','Null','Null'});
            %SetButtons(app,{'off','off','off','off','off','off','off'});
            
            app.valid_datafile = false;
            app.valid_fit = false;
            
            if app.BrukerCheckBox.Value == 1
                
                % Read bruker data, select a FID file
                app.Reco4DUIFigure.Visible = 'off';
                [datafile,app.data_import_path] = uigetfile([app.data_import_path,'*.*'],'Select an FID file');
                app.Reco4DUIFigure.Visible = 'on';
                
                if strcmp(datafile,'fid')
                    
                    app.valid_datafile = true;
                    
                    % Read the Bruker data
                    app.TextMessage('Reading Bruker data ...');
                    info = jcampread([app.data_import_path,'acqp']);
                    fileID = fopen([app.data_import_path,'fid']);
                    app.data = fread(fileID,'int32');
                    fclose(fileID);
                    
                    % Bruker-specific sequence parameters
%                     app.TextMessage('Reading sequence parameters ...');
%                     app.seqpar.matrix = info.acq.size./[2 1 1];  % x y z
%                     app.seqpar.FOV3D = info.acq.fov*10;
%                     app.seqpar.filename = 'Proton';
%                     app.seqpar.tr = info.acq.repetition_time;
%                     app.seqpar.te = info.acq.echo_time;
%                     app.seqpar.alpha = str2num(info.acq.flip_angle);
%                     app.seqpar.NO_ECHOES = 1;
%                     app.seqpar.NO_AVERAGES = str2num(info.NA);
%                     app.seqpar.NO_REPETITIONS = str2num(info.NR);
%                     app.seqpar.date = datetime;
%                     app.seqpar.nucleus = '1H';
%                     app.seqpar.PPL = info.acq.method;
%                     app.seqpar.field_strength = str2num(info.BF1)/42.58;
%                     app.seqpar.filename = 111;
                    
%                     % unsorted complex k-space data
%                     app.TextMessage('Constructing complex k-space data ...');
%                     kreal = app.data(1:2:end);
%                     kim = app.data(2:2:end);
%                     app.kspace = kreal + 1j*kim;
                  
%                     % total acq time in hrs:min:sec
%                     at = seconds(length(app.kspace)*app.seqpar.tr*0.001/app.seqpar.matrix(1));
%                     at.Format = 'hh:mm:ss';
%                     app.seqpar.acqtime = datestr(at,'HH:MM:SS');
                    
                    % k-space trajectory
%                     app.TextMessage('Defining k-space trajectory ...');
%                     dimy = app.seqpar.matrix(2);
%                     dimz = app.seqpar.matrix(3);
%                     ky1rep = round(info.acq.spatial_phase_1 * (dimy-1)/2 + dimy/2);
%                     kz1rep = round(info.acq.spatial_phase_2 * (dimz-1)/2 + dimz/2);
%                     app.ky=[]; for i=1:app.seqpar.NO_REPETITIONS app.ky = [app.ky, ky1rep]; end
%                     app.kz=[]; for i=1:app.seqpar.NO_REPETITIONS app.kz = [app.kz, kz1rep]; end
                    
                end
                
            else        
                
                % MR Solutions data not implemented yet
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_datafile
                
                % Set the buttons for valid file
%                 SetLights(app,{'Green','Null','Null','Null','Null','Null'});
%                 SetButtons(app,{'on','on','off','off','off','off','on'});
                
                % Set other parameters
                SetParametersFcn(app);
          
            else                
                
                % Not a valid data file 
                uialert(app.Reco4DUIFigure,'Please select a valid data file','Error');
                app.data_import_path = '';
                
                % Set the buttons for invalid data file 
%                 SetLights(app,{'Red','Null','Null','Null','Null','Null'});
%                 SetButtons(app,{'on','off','off','off','off','off','on'});
                
            end


        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
        
           % todo
        
        
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
            
        
           % todo
        
        
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
            
        
           % todo


        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            
        
           % todo
        
        
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            
           % todo


        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
           % todo
        
        
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)
            
           % todo
        
        
        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
            
        
           % todo


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
          % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.T1mappingUIFigure = uifigure('Visible', 'off');
            app.T1mappingUIFigure.Name = 'UI Figure';
            app.DashboardPanel = uipanel(app.T1mappingUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadDataButton = uibutton(app.DashboardPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load data';
            app.MRSolutionsCheckBox = uicheckbox(app.DashboardPanel);
            app.MRSolutionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSolutionsCheckBoxValueChanged, true);
            app.MRSolutionsCheckBox.Text = 'MR Solutions';
            app.BrukerCheckBox = uicheckbox(app.DashboardPanel);
            app.BrukerCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrukerCheckBoxValueChanged, true);
            app.BrukerCheckBox.Text = 'Bruker';
            app.BrukerCheckBox.Value = true;
            app.ReconstructButton = uibutton(app.DashboardPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.RegistrationButton = uibutton(app.DashboardPanel, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Text = 'Registration';
            app.SegmentButton = uibutton(app.DashboardPanel, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segment';
            app.RegistrationProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.RegistrationProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitButton = uibutton(app.DashboardPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Text = 'Export Dicom';
            app.ExportNIFTIButton = uibutton(app.DashboardPanel, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.ExportGIFButton = uibutton(app.DashboardPanel, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Text = 'Export GIF';
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RegistrationProgressLabel = uilabel(app.DashboardPanel);
            app.RegistrationProgressLabel.Text = 'progress';
            app.FitProgressGauge = uigauge(app.DashboardPanel, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitProgressLabel = uilabel(app.DashboardPanel);
            app.FitProgressLabel.Text = 'progress';
            app.AbortButton = uibutton(app.DashboardPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.Lamp4 = uilamp(app.DashboardPanel);
            app.Lamp5 = uilamp(app.DashboardPanel);
            app.Lamp6 = uilamp(app.DashboardPanel);
            app.Lamp7 = uilamp(app.DashboardPanel);
            app.Lamp8 = uilamp(app.DashboardPanel);
            app.TitleLabel = uilabel(app.T1mappingUIFigure);
            app.TitleLabel.Text = '3D RF-spoiled gradient-echo T1 mapping';
            app.RawimagesPanel = uipanel(app.T1mappingUIFigure);
            app.RawimagesPanel.Title = 'Raw images';
            app.RawImage = uiaxes(app.RawimagesPanel);
            title(app.RawImage, '')
            xlabel(app.RawImage, '')
            ylabel(app.RawImage, '')
            app.RawImage.XTick = [];
            app.RawImage.YTick = [];
            app.ImageSelectorPanel = uipanel(app.RawimagesPanel);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.SliceEditFieldLabel = uilabel(app.ImageSelectorPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.SliceEditField.Limits = [1 256];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.Value = 1;
            app.SliceSlider = uislider(app.ImageSelectorPanel);
            app.SliceSlider.Limits = [1 256];
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Value = 1;
            app.ImageEditFieldLabel = uilabel(app.ImageSelectorPanel);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.ImageEditField.Limits = [1 256];
            app.ImageEditField.ValueDisplayFormat = '%.0f';
            app.ImageEditField.Value = 1;
            app.ImageSlider = uislider(app.ImageSelectorPanel);
            app.ImageSlider.Limits = [1 256];
            app.ImageSlider.MajorTicks = [];
            app.ImageSlider.MinorTicks = [];
            app.ImageSlider.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.ImageSelectorPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ImageSelectorPanel);
            app.OrientationSpinner.Limits = [1 3];
            app.OrientationSpinner.Value = 1;
            app.SegmentationPanel = uipanel(app.RawimagesPanel);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 256];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.Text = 'Open';
            app.IntensitySlider = uislider(app.RawimagesPanel);
            app.IntensitySlider.Limits = [0 2];
            app.IntensitySlider.MajorTicks = [];
            app.IntensitySlider.MinorTicks = [];
            app.IntensitySlider.Value = 1;
            app.IntensityLabel = uilabel(app.RawimagesPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.ImageLabel = uilabel(app.RawimagesPanel);
            app.ImageLabel.Text = 'Image';
            app.CreditsLabel = uilabel(app.T1mappingUIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0';
            app.TulpImage = uiimage(app.T1mappingUIFigure);
            app.TulpImage.ImageSource = 'AmsterdamUMC.gif';
            app.MessagesPanel = uipanel(app.T1mappingUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessagesBox = uitextarea(app.MessagesPanel);
            app.MessagesBox.Editable = 'off';
            app.SequenceparametersPanel = uipanel(app.T1mappingUIFigure);
            app.SequenceparametersPanel.Title = 'Sequence parameters';
            app.TREditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.TREditFieldLabel.Text = 'TR';
            app.TRViewField = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.TEEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.TEEditFieldLabel.Text = 'TE';
            app.TEViewField = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.SequenceEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.SequenceparametersPanel, 'text');
            app.MatrixEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.MatrixViewField1 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField1.ValueDisplayFormat = '%.0f';
            app.MatrixViewField2 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField2.ValueDisplayFormat = '%.0f';
            app.MatrixViewField3 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.MatrixViewField3.ValueDisplayFormat = '%.0f';
            app.FOVViewField1 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.0f';
            app.FOVViewField2 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField2.ValueDisplayFormat = '%.0f';
            app.FOVmmLabel = uilabel(app.SequenceparametersPanel);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField3 = uieditfield(app.SequenceparametersPanel, 'numeric');
            app.FOVViewField3.ValueDisplayFormat = '%.0f';
            app.FlipanglesEditFieldLabel = uilabel(app.SequenceparametersPanel);
            app.FlipanglesEditFieldLabel.Text = 'Flip angles';
            app.FlipanglesEditField = uieditfield(app.SequenceparametersPanel, 'text');
            app.FitresultsPanel = uipanel(app.T1mappingUIFigure);
            app.FitresultsPanel.Title = 'Fit results';
            app.T1Image = uiaxes(app.FitresultsPanel);
            title(app.T1Image, '')
            xlabel(app.T1Image, '')
            ylabel(app.T1Image, '')
            app.T1Image.XTick = [];
            app.T1Image.YTick = [];
            app.M0Image = uiaxes(app.FitresultsPanel);
            title(app.M0Image, '')
            xlabel(app.M0Image, '')
            ylabel(app.M0Image, '')
            app.M0Image.XTick = [];
            app.M0Image.YTick = [];
            app.T1Label = uilabel(app.FitresultsPanel);
            app.T1Label.Text = 'T1';
            app.M0Label = uilabel(app.FitresultsPanel);
            app.M0Label.Text = 'M0';
            app.FitImageSelectorPanel = uipanel(app.FitresultsPanel);
            app.FitImageSelectorPanel.Title = 'Image Selector';
            app.SliceEditField_2Label_2 = uilabel(app.FitImageSelectorPanel);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.FitSliceEditField = uieditfield(app.FitImageSelectorPanel, 'numeric');
            app.FitSliceEditField.Limits = [1 256];
            app.FitSliceEditField.ValueDisplayFormat = '%.0f';
            app.FitSliceEditField.Value = 1;
            app.FitSliceSlider = uislider(app.FitImageSelectorPanel);
            app.FitSliceSlider.Limits = [1 256];
            app.FitSliceSlider.MajorTicks = [];
            app.FitSliceSlider.MinorTicks = [];
            app.FitSliceSlider.Value = 1;
            app.OrientationSpinner_2Label_2 = uilabel(app.FitImageSelectorPanel);
            app.OrientationSpinner_2Label_2.Text = 'Orientation';
            app.FitOrientationSpinner = uispinner(app.FitImageSelectorPanel);
            app.FitOrientationSpinner.Limits = [1 3];
            app.FitOrientationSpinner.Value = 1;
            app.ColormapButtonGroup = uibuttongroup(app.FitresultsPanel);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Value = true;
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.EditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.MaxLabel = uilabel(app.ColormapButtonGroup);
            app.MaxLabel.Text = 'Max';
            app.AutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.AutoButton.Text = 'Auto';
            app.T1FitFig = uiaxes(app.FitresultsPanel);
            title(app.T1FitFig, '')
            xlabel(app.T1FitFig, 'α')
            ylabel(app.T1FitFig, 'S')
            app.T1FitFig.XLim = [0 100];
            app.T1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.T1FitFig.YTick = [];
            app.DespotT1FitFIg = uiaxes(app.FitresultsPanel);
            title(app.DespotT1FitFIg, '')
            xlabel(app.DespotT1FitFIg, 'α')
            ylabel(app.DespotT1FitFIg, 'S')
            app.DespotT1FitFIg.XLim = [0 100];
            app.DespotT1FitFIg.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.DespotT1FitFIg.YTick = [];
            % Show the figure after all components are created
            app.T1mappingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mapping

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.T1mappingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappingUIFigure)
        end
    end
end